classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ZSpinner            matlab.ui.control.Spinner
        ZSpinnerLabel       matlab.ui.control.Label
        YSpinner            matlab.ui.control.Spinner
        YSpinnerLabel       matlab.ui.control.Label
        XSpinner            matlab.ui.control.Spinner
        XSpinnerLabel       matlab.ui.control.Label
        Z_DButton           matlab.ui.control.Button
        Z_UButton           matlab.ui.control.Button
        X_RButton           matlab.ui.control.Button
        X_LButton           matlab.ui.control.Button
        Y_BButton           matlab.ui.control.Button
        Y_FButton           matlab.ui.control.Button
        DoubleStopCheckBox  matlab.ui.control.CheckBox
        IgnitionCheckBox    matlab.ui.control.CheckBox
        StopButton          matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        %  starts here, insert everything that we require.

        modelR3; % insert UR3 here

        % Insert second robot here

        q0 = [0,0,0,0,0,0];
        qUR3 = [0,pi,pi/2,0,0,-pi];
        TMatrix

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            
            app.modelR3 = UR3;
            % app.modelR3.base = transl([0.5,0,0]); 
            app.modelR3.model.animate(app.q0);
            drawnow();
            hold on;   

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % enable the move button
            app.StartButton.Enable = true;
            
            
            app.TMatrix=(app.modelR3.model.fkine(app.modelR3.model.getpos()).T);
            app.XSpinner.Value=app.TMatrix(1,4);
            app.YSpinner.Value=app.TMatrix(2,4);
            app.ZSpinner.Value=app.TMatrix(3,4);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.DoubleStopCheckBox.Value=1;
            app.IgnitionCheckBox.Value=0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

                if app.DoubleStopCheckBox.Value==0 && app.IgnitionCheckBox.Value==1
                        qMatrix=jtraj(app.q0,app.qUR3,50);
                        for robotStepIndex = 1:size(qMatrix,1)
                            
                            app.modelR3.model.animate(qMatrix(robotStepIndex,:));
                            drawnow();
                        end
                end
        end

        % Button pushed function: Y_FButton
        function Y_FButtonPushed(app, event)
            yT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,0.1,0);
            yQ = app.modelR3.model.ikcon(yT);

            
            yMatrix = jtraj(app.modelR3.model.getpos(),yQ,20);
            for robotStepIndex = 1:size(yMatrix,1)
                app.modelR3.model.animate(yMatrix(robotStepIndex,:));
                drawnow();
            end
        end

        % Button pushed function: X_RButton
        function X_RButtonPushed(app, event)
            xT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0.1,0,0);
            xQ = app.modelR3.model.ikcon(xT);

            
            xMatrix = jtraj(app.modelR3.model.getpos(),xQ,20);
            for robotStepIndex = 1:size(xMatrix,1)
                app.modelR3.model.animate(xMatrix(robotStepIndex,:));
                drawnow();
            end
        end

        % Button pushed function: X_LButton
        function X_LButtonPushed(app, event)
            xT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(-0.1,0,0);
            xQ = app.modelR3.model.ikcon(xT);

            
            xMatrix = jtraj(app.modelR3.model.getpos(),xQ,20);
            for robotStepIndex = 1:size(xMatrix,1)
                app.modelR3.model.animate(xMatrix(robotStepIndex,:));
                drawnow();
            end
        end

        % Button pushed function: Y_BButton
        function Y_BButtonPushed(app, event)
            yT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,-0.1,0);
            yQ = app.modelR3.model.ikcon(yT);

            
            yMatrix = jtraj(app.modelR3.model.getpos(),yQ,20);
            for robotStepIndex = 1:size(yMatrix,1)
                app.modelR3.model.animate(yMatrix(robotStepIndex,:));
                drawnow();
            end
        end

        % Button pushed function: Z_DButton
        function Z_DButtonPushed(app, event)
            zT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,0,-0.1);
            zQ = app.modelR3.model.ikcon(zT);

            
            zMatrix = jtraj(app.modelR3.model.getpos(),zQ,20);
            for robotStepIndex = 1:size(zMatrix,1)
                app.modelR3.model.animate(zMatrix(robotStepIndex,:));
                drawnow();
            end
        end

        % Button pushed function: Z_UButton
        function Z_UButtonPushed(app, event)
            T = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,0,0.1);
            Q = app.modelR3.model.ikcon(T);

            
            zMatrix = jtraj(app.modelR3.model.getpos(),Q,20);
            for robotStepIndex = 1:size(zMatrix,1)
                app.modelR3.model.animate(zMatrix(robotStepIndex,:));
                drawnow();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.IgnitionCheckBox = uicheckbox(app.UIFigure);
            app.IgnitionCheckBox.Text = 'Ignition';
            app.DoubleStopCheckBox = uicheckbox(app.UIFigure);
            app.DoubleStopCheckBox.Text = 'Double Stop';
            app.Y_FButton = uibutton(app.UIFigure, 'push');
            app.Y_FButton.ButtonPushedFcn = createCallbackFcn(app, @Y_FButtonPushed, true);
            app.Y_FButton.Text = 'Y_F';
            app.Y_BButton = uibutton(app.UIFigure, 'push');
            app.Y_BButton.ButtonPushedFcn = createCallbackFcn(app, @Y_BButtonPushed, true);
            app.Y_BButton.Text = 'Y_B';
            app.X_LButton = uibutton(app.UIFigure, 'push');
            app.X_LButton.ButtonPushedFcn = createCallbackFcn(app, @X_LButtonPushed, true);
            app.X_LButton.Text = 'X_L';
            app.X_RButton = uibutton(app.UIFigure, 'push');
            app.X_RButton.ButtonPushedFcn = createCallbackFcn(app, @X_RButtonPushed, true);
            app.X_RButton.Text = 'X_R';
            app.Z_UButton = uibutton(app.UIFigure, 'push');
            app.Z_UButton.ButtonPushedFcn = createCallbackFcn(app, @Z_UButtonPushed, true);
            app.Z_UButton.Text = 'Z_U';
            app.Z_DButton = uibutton(app.UIFigure, 'push');
            app.Z_DButton.ButtonPushedFcn = createCallbackFcn(app, @Z_DButtonPushed, true);
            app.Z_DButton.Text = 'Z_D';
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end